classdef gui3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UIAxes_2  matlab.ui.control.UIAxes
        Switch    matlab.ui.control.Switch
        Switch_2  matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            cla(app.UIAxes);
            syms x;
            hold(app.UIAxes, "on")
            
            if value == "Show"
                fplot(app.UIAxes, exp(-x^2), [-2, 2]);
            end
            hold(app.UIAxes, "off")
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            cla(app.UIAxes_2);
            syms x;
            hold(app.UIAxes_2, "on")
            if value == "Show"
                fplot(app.UIAxes_2, sin(x), [0, 4*pi]);
            end
            hold(app.UIAxes_2, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'f(x) = e^{-x^2}')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'g(x) = sin(x)')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 12.5663706143592];
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Hide', 'Show'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Hide';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Hide', 'Show'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Hide';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end